classdef ffauxivsolver < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout    matlab.ui.container.GridLayout
        Erase_Button  matlab.ui.control.StateButton
        Faux_Button   matlab.ui.control.StateButton
        Sword_Button  matlab.ui.control.StateButton
        Baux_Button   matlab.ui.control.StateButton
        Miss_Button   matlab.ui.control.StateButton
        Block_Button  matlab.ui.control.StateButton
        Button_36     matlab.ui.control.Button
        Button_35     matlab.ui.control.Button
        Button_34     matlab.ui.control.Button
        Button_33     matlab.ui.control.Button
        Button_32     matlab.ui.control.Button
        Button_31     matlab.ui.control.Button
        Button_30     matlab.ui.control.Button
        Button_29     matlab.ui.control.Button
        Button_28     matlab.ui.control.Button
        Button_27     matlab.ui.control.Button
        Button_26     matlab.ui.control.Button
        Button_25     matlab.ui.control.Button
        Button_24     matlab.ui.control.Button
        Button_23     matlab.ui.control.Button
        Button_22     matlab.ui.control.Button
        Button_21     matlab.ui.control.Button
        Button_20     matlab.ui.control.Button
        Button_19     matlab.ui.control.Button
        Button_18     matlab.ui.control.Button
        Button_17     matlab.ui.control.Button
        Button_16     matlab.ui.control.Button
        Button_15     matlab.ui.control.Button
        Button_14     matlab.ui.control.Button
        Button_13     matlab.ui.control.Button
        Button_12     matlab.ui.control.Button
        Button_11     matlab.ui.control.Button
        Button_10     matlab.ui.control.Button
        Button_9      matlab.ui.control.Button
        Button_8      matlab.ui.control.Button
        Button_7      matlab.ui.control.Button
        Button_6      matlab.ui.control.Button
        Button_5      matlab.ui.control.Button
        Button_4      matlab.ui.control.Button
        Button_3      matlab.ui.control.Button
        Button_2      matlab.ui.control.Button
        Button        matlab.ui.control.Button
    end

    properties (Access = public)
        brush (1,1) double = NaN;
        pattern (6,6) double = NaN(6);
    end
    
    
    methods (Access = private)
        
        function buttons = patternButtons(comp)
            buttons = [comp.Button, ...
                comp.Button_7, ...
                comp.Button_13, ...
                comp.Button_19, ...
                comp.Button_25, ...
                comp.Button_31, ...
                comp.Button_2, ...
                comp.Button_8, ...
                comp.Button_14, ...
                comp.Button_20, ...
                comp.Button_26, ...
                comp.Button_32, ...
                comp.Button_3, ...
                comp.Button_9, ...
                comp.Button_15, ...
                comp.Button_21, ...
                comp.Button_27, ...
                comp.Button_33, ...
                comp.Button_4, ...
                comp.Button_10, ...
                comp.Button_16, ...
                comp.Button_22, ...
                comp.Button_28, ...
                comp.Button_34, ...
                comp.Button_5, ...
                comp.Button_11, ...
                comp.Button_17, ...
                comp.Button_23, ...
                comp.Button_29, ...
                comp.Button_35, ...
                comp.Button_6, ...
                comp.Button_12, ...
                comp.Button_18, ...
                comp.Button_24, ...
                comp.Button_30, ...
                comp.Button_36];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Block_Button
        function Block_ButtonValueChanged(comp, event)
            value = comp.Block_Button.Value;
            comp.Miss_Button.Value = false;
            comp.Baux_Button.Value = false;
            comp.Sword_Button.Value = false;
            comp.Faux_Button.Value = false;
            comp.Erase_Button.Value = false;

            comp.brush = 0;
        end

        % Value changed function: Miss_Button
        function Miss_ButtonValueChanged(comp, event)
            value = comp.Miss_Button.Value;
            comp.Block_Button.Value = false;
            comp.Baux_Button.Value = false;
            comp.Sword_Button.Value = false;
            comp.Faux_Button.Value = false;
            comp.Erase_Button.Value = false;

            comp.brush = 1;
        end

        % Value changed function: Baux_Button
        function Baux_ButtonValueChanged(comp, event)
            value = comp.Baux_Button.Value;
            comp.Block_Button.Value = false;
            comp.Miss_Button.Value = false;
            comp.Sword_Button.Value = false;
            comp.Faux_Button.Value = false;
            comp.Erase_Button.Value = false;

            comp.brush = 2;
        end

        % Value changed function: Sword_Button
        function Sword_ButtonValueChanged(comp, event)
            value = comp.Sword_Button.Value;
            comp.Block_Button.Value = false;
            comp.Miss_Button.Value = false;
            comp.Baux_Button.Value = false;
            comp.Faux_Button.Value = false;
            comp.Erase_Button.Value = false;

            comp.brush = 3;
        end

        % Value changed function: Faux_Button
        function Faux_ButtonValueChanged(comp, event)
            value = comp.Faux_Button.Value;
            comp.Block_Button.Value = false;
            comp.Miss_Button.Value = false;
            comp.Baux_Button.Value = false;
            comp.Sword_Button.Value = false;
            comp.Erase_Button.Value = false;

            comp.brush = 4;
        end

        % Value changed function: Erase_Button
        function Erase_ButtonValueChanged(comp, event)
            value = comp.Erase_Button.Value;
            comp.Block_Button.Value = false;
            comp.Miss_Button.Value = false;
            comp.Baux_Button.Value = false;
            comp.Sword_Button.Value = false;
            comp.Faux_Button.Value = false;

            comp.brush = NaN;
        end

        % Button pushed function: Button, Button_10, Button_11, Button_12, 
        % ...and 32 other components
        function Grid_ButtonPushed(comp, event)
            comp.pattern(event.Source.Layout.Row, event.Source.Layout.Column) = comp.brush;
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            buttons = comp.patternButtons();
            for iB = 1:36
                if isnan(comp.pattern(iB))
                    buttons(iB).BackgroundColor = [0.5 0.5 0.5];
                else
                    switch comp.pattern(iB)
                        case 0
                            buttons(iB).BackgroundColor = [0 0 0];
                        case 1
                            buttons(iB).BackgroundColor = [1 1 1];
                        case 2
                            buttons(iB).BackgroundColor = [0 1 0];
                        case 3
                            buttons(iB).BackgroundColor = [0 0 1];
                        case 4
                            buttons(iB).BackgroundColor = [1 0 0];
                    end
                end
            end
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 360 420];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.GridLayout = uigridlayout(comp);
            comp.Button = uibutton(comp.GridLayout, 'push');
            comp.Button.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @Grid_ButtonPushed, true);
            comp.Button.Text = '';
            comp.Button_2 = uibutton(comp.GridLayout, 'push');
            comp.Button_2.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @Grid_ButtonPushed, true);
            comp.Button_2.Text = '';
            comp.Button_3 = uibutton(comp.GridLayout, 'push');
            comp.Button_3.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @Grid_ButtonPushed, true);
            comp.Button_3.Text = '';
            comp.Button_4 = uibutton(comp.GridLayout, 'push');
            comp.Button_4.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @Grid_ButtonPushed, true);
            comp.Button_4.Text = '';
            comp.Button_5 = uibutton(comp.GridLayout, 'push');
            comp.Button_5.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @Grid_ButtonPushed, true);
            comp.Button_5.Text = '';
            comp.Button_6 = uibutton(comp.GridLayout, 'push');
            comp.Button_6.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @Grid_ButtonPushed, true);
            comp.Button_6.Text = '';
            comp.Button_7 = uibutton(comp.GridLayout, 'push');
            comp.Button_7.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @Grid_ButtonPushed, true);
            comp.Button_7.Text = '';
            comp.Button_8 = uibutton(comp.GridLayout, 'push');
            comp.Button_8.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @Grid_ButtonPushed, true);
            comp.Button_8.Text = '';
            comp.Button_9 = uibutton(comp.GridLayout, 'push');
            comp.Button_9.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @Grid_ButtonPushed, true);
            comp.Button_9.Text = '';
            comp.Button_10 = uibutton(comp.GridLayout, 'push');
            comp.Button_10.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @Grid_ButtonPushed, true);
            comp.Button_10.Text = '';
            comp.Button_11 = uibutton(comp.GridLayout, 'push');
            comp.Button_11.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @Grid_ButtonPushed, true);
            comp.Button_11.Text = '';
            comp.Button_12 = uibutton(comp.GridLayout, 'push');
            comp.Button_12.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @Grid_ButtonPushed, true);
            comp.Button_12.Text = '';
            comp.Button_13 = uibutton(comp.GridLayout, 'push');
            comp.Button_13.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @Grid_ButtonPushed, true);
            comp.Button_13.Text = '';
            comp.Button_14 = uibutton(comp.GridLayout, 'push');
            comp.Button_14.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @Grid_ButtonPushed, true);
            comp.Button_14.Text = '';
            comp.Button_15 = uibutton(comp.GridLayout, 'push');
            comp.Button_15.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @Grid_ButtonPushed, true);
            comp.Button_15.Text = '';
            comp.Button_16 = uibutton(comp.GridLayout, 'push');
            comp.Button_16.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @Grid_ButtonPushed, true);
            comp.Button_16.Text = '';
            comp.Button_17 = uibutton(comp.GridLayout, 'push');
            comp.Button_17.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @Grid_ButtonPushed, true);
            comp.Button_17.Text = '';
            comp.Button_18 = uibutton(comp.GridLayout, 'push');
            comp.Button_18.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @Grid_ButtonPushed, true);
            comp.Button_18.Text = '';
            comp.Button_19 = uibutton(comp.GridLayout, 'push');
            comp.Button_19.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @Grid_ButtonPushed, true);
            comp.Button_19.Text = '';
            comp.Button_20 = uibutton(comp.GridLayout, 'push');
            comp.Button_20.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @Grid_ButtonPushed, true);
            comp.Button_20.Text = '';
            comp.Button_21 = uibutton(comp.GridLayout, 'push');
            comp.Button_21.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @Grid_ButtonPushed, true);
            comp.Button_21.Text = '';
            comp.Button_22 = uibutton(comp.GridLayout, 'push');
            comp.Button_22.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @Grid_ButtonPushed, true);
            comp.Button_22.Text = '';
            comp.Button_23 = uibutton(comp.GridLayout, 'push');
            comp.Button_23.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @Grid_ButtonPushed, true);
            comp.Button_23.Text = '';
            comp.Button_24 = uibutton(comp.GridLayout, 'push');
            comp.Button_24.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @Grid_ButtonPushed, true);
            comp.Button_24.Text = '';
            comp.Button_25 = uibutton(comp.GridLayout, 'push');
            comp.Button_25.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @Grid_ButtonPushed, true);
            comp.Button_25.Text = '';
            comp.Button_26 = uibutton(comp.GridLayout, 'push');
            comp.Button_26.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @Grid_ButtonPushed, true);
            comp.Button_26.Text = '';
            comp.Button_27 = uibutton(comp.GridLayout, 'push');
            comp.Button_27.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @Grid_ButtonPushed, true);
            comp.Button_27.Text = '';
            comp.Button_28 = uibutton(comp.GridLayout, 'push');
            comp.Button_28.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @Grid_ButtonPushed, true);
            comp.Button_28.Text = '';
            comp.Button_29 = uibutton(comp.GridLayout, 'push');
            comp.Button_29.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @Grid_ButtonPushed, true);
            comp.Button_29.Text = '';
            comp.Button_30 = uibutton(comp.GridLayout, 'push');
            comp.Button_30.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @Grid_ButtonPushed, true);
            comp.Button_30.Text = '';
            comp.Button_31 = uibutton(comp.GridLayout, 'push');
            comp.Button_31.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @Grid_ButtonPushed, true);
            comp.Button_31.Text = '';
            comp.Button_32 = uibutton(comp.GridLayout, 'push');
            comp.Button_32.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @Grid_ButtonPushed, true);
            comp.Button_32.Text = '';
            comp.Button_33 = uibutton(comp.GridLayout, 'push');
            comp.Button_33.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @Grid_ButtonPushed, true);
            comp.Button_33.Text = '';
            comp.Button_34 = uibutton(comp.GridLayout, 'push');
            comp.Button_34.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @Grid_ButtonPushed, true);
            comp.Button_34.Text = '';
            comp.Button_35 = uibutton(comp.GridLayout, 'push');
            comp.Button_35.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @Grid_ButtonPushed, true);
            comp.Button_35.Text = '';
            comp.Button_36 = uibutton(comp.GridLayout, 'push');
            comp.Button_36.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @Grid_ButtonPushed, true);
            comp.Button_36.Text = '';
            comp.Block_Button = uibutton(comp.GridLayout, 'state');
            comp.Block_Button.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @Block_ButtonValueChanged, true);
            comp.Block_Button.Text = 'Block';
            comp.Miss_Button = uibutton(comp.GridLayout, 'state');
            comp.Miss_Button.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @Miss_ButtonValueChanged, true);
            comp.Miss_Button.Text = 'Miss';
            comp.Baux_Button = uibutton(comp.GridLayout, 'state');
            comp.Baux_Button.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @Baux_ButtonValueChanged, true);
            comp.Baux_Button.Text = 'Baux';
            comp.Sword_Button = uibutton(comp.GridLayout, 'state');
            comp.Sword_Button.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @Sword_ButtonValueChanged, true);
            comp.Sword_Button.Text = 'Swords';
            comp.Faux_Button = uibutton(comp.GridLayout, 'state');
            comp.Faux_Button.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @Faux_ButtonValueChanged, true);
            comp.Faux_Button.Text = 'Faux';
            comp.Erase_Button = uibutton(comp.GridLayout, 'state');
            comp.Erase_Button.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @Erase_ButtonValueChanged, true);
            comp.Erase_Button.Text = 'Erase';
        end
    end
end